classdef MasterGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ShanleyandJessesMXET400ProjectTheHemmingwayLabel  matlab.ui.control.Label
        GetForwardDynamicsButton       matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        Image                          matlab.ui.control.Image
        ForceexertedonJointsLabel      matlab.ui.control.Label
        TimeStepLabel                  matlab.ui.control.Label
        D0EditFieldLabel               matlab.ui.control.Label
        D0EditField                    matlab.ui.control.NumericEditField
        D1EditFieldLabel               matlab.ui.control.Label
        D1EditField                    matlab.ui.control.NumericEditField
        D2EditFieldLabel               matlab.ui.control.Label
        D2EditField                    matlab.ui.control.NumericEditField
        D3EditFieldLabel               matlab.ui.control.Label
        D3EditField                    matlab.ui.control.NumericEditField
        D4EditFieldLabel               matlab.ui.control.Label
        D4EditField                    matlab.ui.control.NumericEditField
        D5EditFieldLabel               matlab.ui.control.Label
        D5EditField                    matlab.ui.control.NumericEditField
        TIEditFieldLabel               matlab.ui.control.Label
        TIEditField                    matlab.ui.control.NumericEditField
        TFEditFieldLabel               matlab.ui.control.Label
        TFEditField                    matlab.ui.control.NumericEditField
        TherunbuttoncalculatestheforwarddynamicsoftherobotLabel  matlab.ui.control.Label
        IneachoftheseblocksentertheforceinNewtonsoneachlinkLabel  matlab.ui.control.Label
        InitialTimeLabel               matlab.ui.control.Label
        FinaltimeLabel                 matlab.ui.control.Label
        Link6NLabel                    matlab.ui.control.Label
        Link5NLabel                    matlab.ui.control.Label
        ForwardDynamicsLabel           matlab.ui.control.Label
        Link4NLabel                    matlab.ui.control.Label
        Link3NLabel                    matlab.ui.control.Label
        Link2NLabel                    matlab.ui.control.Label
        Link1NLabel                    matlab.ui.control.Label
        GetIInverseKinematicsButton    matlab.ui.control.Button
        CoordinatesofEndEffectorLabel  matlab.ui.control.Label
        CoordinateXEditFieldLabel      matlab.ui.control.Label
        CoordinateXEditField           matlab.ui.control.NumericEditField
        CoordinateYEditFieldLabel      matlab.ui.control.Label
        CoordinateYEditField           matlab.ui.control.NumericEditField
        CoorindateZEditFieldLabel      matlab.ui.control.Label
        CoorindateZEditField           matlab.ui.control.NumericEditField
        FwdHide                        matlab.ui.control.Image
        FollowPathButton               matlab.ui.control.Button
        TimeEditFieldLabel             matlab.ui.control.Label
        TimeEditField                  matlab.ui.control.NumericEditField
        CenterXEditFieldLabel          matlab.ui.control.Label
        CenterXEditField               matlab.ui.control.NumericEditField
        CenterYEditFieldLabel          matlab.ui.control.Label
        CenterYEditField               matlab.ui.control.NumericEditField
        CenterZEditFieldLabel          matlab.ui.control.Label
        CenterZEditField               matlab.ui.control.NumericEditField
        RadiusEditFieldLabel           matlab.ui.control.Label
        RadiusEditField                matlab.ui.control.NumericEditField
        TherobotwilltracetheshapeoutlineLabel  matlab.ui.control.Label
        InverseKinematicsLabel         matlab.ui.control.Label
        PathFollowingLabel             matlab.ui.control.Label
        AngularVelocityoneachlinkRadsSecondLabel_3  matlab.ui.control.Label
        TheFollowPathButtonwillhavetheUR5tracetheshapeLabel  matlab.ui.control.Label
        RadiusoftheshapeLabel          matlab.ui.control.Label
        TheZcoordinateoftheshapecenterLabel  matlab.ui.control.Label
        ThetimetakentotracetheshapeLabel  matlab.ui.control.Label
        TheXcoordinateoftheshapecenterLabel  matlab.ui.control.Label
        TheYcoordinateoftheshapecenterLabel  matlab.ui.control.Label
        IneachoftheseblocksenterthecoordinatesfortheendeffectorLabel  matlab.ui.control.Label
        InvHide                        matlab.ui.control.Image
        PathHide                       matlab.ui.control.Image
        PathSwitchLabel                matlab.ui.control.Label
        PathSwitch                     matlab.ui.control.ToggleSwitch
        InverseSwitchLabel             matlab.ui.control.Label
        InverseSwitch                  matlab.ui.control.ToggleSwitch
        ForwardSwitchLabel             matlab.ui.control.Label
        ForwardSwitch                  matlab.ui.control.ToggleSwitch
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetForwardDynamicsButton
        function GetForwardDynamicsButtonPushed(app, event)
 %load exampleRobots.mat lbr
lbr = importrobot('ur5_robot.urdf');
lbr.DataFormat = 'row';
q = homeConfiguration(lbr);
%Change the d values for the force on each joint of the system
d0=app.D0EditField.Value;
d1=app.D1EditField.Value;
d2=app.D2EditField.Value;
d3=app.D3EditField.Value;
d4=app.D4EditField.Value;
d5=app.D5EditField.Value;
wrench = [d0 d1 d2 d3 d4 d5]; %this is the force on the robot
%Change time of the system if they want to
ti = app.TIEditField.Value;
  
tf = app.TFEditField.Value;
fext = externalForce(lbr,'ee_link',wrench,q);
qddot = forwardDynamics(lbr,q,[],[],fext);
display(qddot)
FinalPosition = [qddot];
gripper = 'ee_link';
qddot1 = qddot';
figure
show(lbr,q(1,:));
%show(lbr,qddot(1,:));
%view(2)
hold on
framesPerSecond = 15;
r = rateControl(framesPerSecond);
%Animate
p = plot3(FinalPosition(1,1), FinalPosition(1,2), FinalPosition(1,3));
for i = 1:size(qddot)
    show(lbr,qddot(1,:));
    p.XData(i) = FinalPosition(i,1);
    p.YData(i) = FinalPosition(i,2);
    p.ZData(i) = FinalPosition(i,3);
    waitfor(r);
    drawnow
end
%for i = ti:tf
 %   show(lbr,qddot(1,:));
  %  drawnow
%end
hold on
        end

        % Image clicked function: PathHide
        function DropDownValueChanged(app, event)
            %app.fullHide.HandleVisibility=;
       
           
            value = app.DropDown.Value;
            disp(value)
            size(value)


%             if value == 'Forward'
%                  disp("fwd");
%                  app.FwdHide.Visible=0;
%                  app.InvHide.Visible=1;
%                app.PathHide.Visible=1;
%              end
             
%         if value == 'Inverse'
%                  disp("INV");
%                  app.FwdHide.Visible=1;
%                  app.InvHide.Visible=0;
%                  app.PathHide.Visible=1;
%              end
%                
             if app.PathSwitch.Value==on
                 app.FwdHide.Visible=1;
                   app.InvHide.Visible=1;
                  app.PathHide.Visible=0;
             end
%          if app.DropDown.Value== 'Follow'
%              disp("test");
%              
% %                if value == 'Follow'
% %                   disp("Path");
% %                 app.FwdHide.Visible=1;
% %                   app.InvHide.Visible=1;
% %                  app.PathHide.Visible=0;
%               end
              
        
        end

        % Button pushed function: GetIInverseKinematicsButton
        function GetIInverseKinematicsButtonPushed(app, event)
%load exampleRobots.mat lbr
lbr = importrobot('ur5_robot.urdf');
lbr.DataFormat = 'row';
lbr.Gravity = [0 0 -9.81];
q = homeConfiguration(lbr);
numWaypoints = 5;
qWaypoints = repmat(q, numWaypoints, 1);
%This goes to the desired location
a3 = app.CoordinateXEditField.Value;  %z
a2 = app.CoordinateYEditField.Value;  %y
a1 = app.CoorindateZEditField.Value;  %z
location = [a1 a2 a3]; 
FinalPosition = [location];
gripper = 'ee_link';
gik = generalizedInverseKinematics('RigidBodyTree', lbr, ...
    'ConstraintInputs', {'cartesian','position','aiming','orientation','joint'});
%Make constraint objects, cartesian bounds
heightAboveTable = constraintCartesianBounds(gripper);
heightAboveTable.Bounds = [-inf, inf; ...
                           -inf, inf; ...
                           0.1, inf];
%Create contraint on the position
distanceFromFinal = constraintPositionTarget('ee_link');
distanceFromFinal.ReferenceBody = gripper;
%distanceFromFinal.PositionTolerance = 0.005;
%Create an aiming constant
alignWithFinal = constraintAiming('ee_link');
alignWithFinal.TargetPoint = [a1 a2 a3];
%Create joint position bounds constraint
limitJointChange = constraintJointBounds(lbr);
%Orientation constraint
fixOrientation = constraintOrientationTarget(gripper);
fixOrientation.OrientationTolerance = deg2rad(1);
%Places gripper x distance from the cup
intermediateDistance = 0;
%Constrain object weight
limitJointChange.Weights = zeros(size(limitJointChange.Weights));
fixOrientation.Weights = 0;
%Set target position for the cup
distanceFromFinal.TargetPosition = [0,0,intermediateDistance];
%Solve for robot configuation by using gik solver
[qWaypoints(2,:),solutionInfo] = gik(q, heightAboveTable, ...
                       distanceFromFinal, alignWithFinal, fixOrientation, ...
                       limitJointChange);
%Re-enable the joint position 
limitJointChange.Weights = ones(size(limitJointChange.Weights));
fixOrientation.Weights = 1;
%Disable the align-cup restraint
alignWithCup.Weights = 0;
%Set the orientation constraint
fixOrientation.TargetOrientation = ...
    tform2quat(getTransform(lbr,qWaypoints(2,:),gripper));
finalDistanceFromFinal = 0;
distanceFromFinalValues = linspace(intermediateDistance, finalDistanceFromFinal, numWaypoints-1);
%Define the max allowed change in joint positions
maxJointChange = deg2rad(10);
%Solve for each remaing waypoint
for k = 3:numWaypoints
    % Update the target position.
    distanceFromFinal.TargetPosition(3) = distanceFromFinalValues(k-1);
    % Restrict the joint positions to lie close to their previous values.
    limitJointChange.Bounds = [qWaypoints(k-1,:)' - maxJointChange, ...
                               qWaypoints(k-1,:)' + maxJointChange];
    % Solve for a configuration and add it to the waypoints array.
    [qWaypoints(k,:),solutionInfo] = gik(qWaypoints(k-1,:), ...
                                         heightAboveTable, ...
                                         distanceFromFinal, alignWithFinal, ...
                                         fixOrientation, limitJointChange);
end
framerate = 15;
r = rateControl(framerate);
tFinal = 10;
tWaypoints = [0,linspace(tFinal/2,tFinal,size(qWaypoints,1)-1)];
numFrames = tFinal*framerate;
qInterp = pchip(tWaypoints,qWaypoints',linspace(0,tFinal,numFrames))';
%Compute the gripper postion for each interpolated configuration
gripperPosition = zeros(numFrames,3);
%import the simulink
%sim('thesnakewouldhavebeennier')
figure
show(lbr,q(1,:));
%view(2)
%ax = gca;
%ax.Projection = 'orthographic';
hold on
%plot(points(:,1),points(:,2),'k')
%axis([-0.1 0.7 -0.3 0.5])
framesPerSecond = 15;
r = rateControl(framesPerSecond);
%Animate
p = plot3(gripperPosition(1,1), gripperPosition(1,2), gripperPosition(1,3));
hold on
for k = 1:size(qInterp,1)
    show(lbr, qInterp(k,:), 'PreservePlot', false);
    p.XData(k) = gripperPosition(k,1);
    p.YData(k) = gripperPosition(k,2);
    p.ZData(k) = gripperPosition(k,3);
    waitfor(r);
end
hold off
            
  
        end

        % Button pushed function: FollowPathButton
        function FollowPathButtonPushed(app, event)
lbr = importrobot('ur5_robot.urdf');
lbr.DataFormat = 'row';
akane = 'wrist_3_link';
killua = app.TimeEditField.Value;     %this
t = (0:killua:10)'; % Time
count = length(t);
c1 = app.CenterXEditField.Value;            %this
c2 = app.CenterYEditField.Value;            %ghix one
c3 = app.CenterZEditField.Value;               %this one
center = [c1 c2 c3];
radius = app.RadiusEditField.Value;           %this
theta = t*(2*pi/t(end));
points = center + radius*[cos(theta) sin(theta) zeros(size(theta))];
q = homeConfiguration(lbr);
ndof = length(q);
qs = zeros(count, ndof);
ik = inverseKinematics('RigidBodyTree', lbr);
weights = [0, 0, 0, 1, 1, 0];
endEffector = 'ee_link';
qInitial = q; % Use home configuration as the initial guess
for i = 1:count
    % Solve for the configuration satisfying the desired end effector
    % position
    point = points(i,:);
    qSol = ik(endEffector,trvec2tform(point),weights,qInitial);
    % Store the configuration
    qs(i,:) = qSol;
    % Start from prior solution
    qInitial = qSol;
end
figure
show(lbr,qs(1,:));
view(2)
ax = gca;
ax.Projection = 'orthographic';
hold on
plot(points(:,1),points(:,2),'k')
%axis([-0.1 0.7 -0.3 0.5])
framesPerSecond = 15;
r = rateControl(framesPerSecond);
for i = 1:count
    show(lbr,qs(i,:),'PreservePlot',false);
    drawnow
    waitfor(r);
end
        end

        % Callback function
        function fullHideImageClicked(app, event)
%                 app.fullHide.Visible=0;
%                 app.FwdHide.Visible=0;
%                 app.InvHide.Visible=0;
%                 app.PathHide.Visible=0;
        end

        % Value changed function: ForwardSwitch
        function ForwardSwitchValueChanged(app, event)
            value = app.ForwardSwitch.Value;
            %disp(value);
            if value == "Off"
                
                app.FwdHide.Visible=1;
            else
                app.FwdHide.Visible=0;
            end
            
        end

        % Value changed function: PathSwitch
        function PathSwitchValueChanged(app, event)
            value = app.PathSwitch.Value;
            if value == "Off"
                
                app.PathHide.Visible=1;
            else
                app.PathHide.Visible=0;
            end
        end

        % Value changed function: InverseSwitch
        function InverseSwitchValueChanged(app, event)
            value = app.InverseSwitch.Value;
             if value == "Off"
                
                app.InvHide.Visible=1;
            else
                app.InvHide.Visible=0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ShanleyandJessesMXET400ProjectTheHemmingwayLabel = uilabel(app.UIFigure);
            app.ShanleyandJessesMXET400ProjectTheHemmingwayLabel.Text = 'Shanley and Jesse''s MXET 400 Project: The Hemmingway';
            app.GetForwardDynamicsButton = uibutton(app.UIFigure, 'push');
            app.GetForwardDynamicsButton.ButtonPushedFcn = createCallbackFcn(app, @GetForwardDynamicsButtonPushed, true);
            app.GetForwardDynamicsButton.Text = 'Get Forward Dynamics';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Path')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Capture hemminway.PNG';
            app.ForceexertedonJointsLabel = uilabel(app.UIFigure);
            app.ForceexertedonJointsLabel.Text = 'Force exerted on Joints';
            app.TimeStepLabel = uilabel(app.UIFigure);
            app.TimeStepLabel.Text = 'Time Step';
            app.D0EditFieldLabel = uilabel(app.UIFigure);
            app.D0EditFieldLabel.Text = 'D0';
            app.D0EditField = uieditfield(app.UIFigure, 'numeric');
            app.D1EditFieldLabel = uilabel(app.UIFigure);
            app.D1EditFieldLabel.Text = 'D1';
            app.D1EditField = uieditfield(app.UIFigure, 'numeric');
            app.D2EditFieldLabel = uilabel(app.UIFigure);
            app.D2EditFieldLabel.Text = 'D2';
            app.D2EditField = uieditfield(app.UIFigure, 'numeric');
            app.D3EditFieldLabel = uilabel(app.UIFigure);
            app.D3EditFieldLabel.Text = 'D3';
            app.D3EditField = uieditfield(app.UIFigure, 'numeric');
            app.D4EditFieldLabel = uilabel(app.UIFigure);
            app.D4EditFieldLabel.Text = 'D4';
            app.D4EditField = uieditfield(app.UIFigure, 'numeric');
            app.D5EditFieldLabel = uilabel(app.UIFigure);
            app.D5EditFieldLabel.Text = 'D5';
            app.D5EditField = uieditfield(app.UIFigure, 'numeric');
            app.TIEditFieldLabel = uilabel(app.UIFigure);
            app.TIEditFieldLabel.Text = 'TI';
            app.TIEditField = uieditfield(app.UIFigure, 'numeric');
            app.TFEditFieldLabel = uilabel(app.UIFigure);
            app.TFEditFieldLabel.Text = 'TF';
            app.TFEditField = uieditfield(app.UIFigure, 'numeric');
            app.TherunbuttoncalculatestheforwarddynamicsoftherobotLabel = uilabel(app.UIFigure);
            app.TherunbuttoncalculatestheforwarddynamicsoftherobotLabel.Text = {'The run button calculates the '; 'forward dynamics of the robot. '};
            app.IneachoftheseblocksentertheforceinNewtonsoneachlinkLabel = uilabel(app.UIFigure);
            app.IneachoftheseblocksentertheforceinNewtonsoneachlinkLabel.Text = {'In each of these blocks, enter the '; 'force in Newtons on each link'};
            app.InitialTimeLabel = uilabel(app.UIFigure);
            app.InitialTimeLabel.Text = 'Initial Time';
            app.FinaltimeLabel = uilabel(app.UIFigure);
            app.FinaltimeLabel.Text = 'Final time';
            app.Link6NLabel = uilabel(app.UIFigure);
            app.Link6NLabel.Text = 'Link 6 (N)';
            app.Link5NLabel = uilabel(app.UIFigure);
            app.Link5NLabel.Text = 'Link 5 (N)';
            app.ForwardDynamicsLabel = uilabel(app.UIFigure);
            app.ForwardDynamicsLabel.Text = 'Forward Dynamics';
            app.Link4NLabel = uilabel(app.UIFigure);
            app.Link4NLabel.Text = 'Link 4 (N)';
            app.Link3NLabel = uilabel(app.UIFigure);
            app.Link3NLabel.Text = 'Link 3 (N)';
            app.Link2NLabel = uilabel(app.UIFigure);
            app.Link2NLabel.Text = 'Link 2 (N)';
            app.Link1NLabel = uilabel(app.UIFigure);
            app.Link1NLabel.Text = 'Link 1 (N)';
            app.GetIInverseKinematicsButton = uibutton(app.UIFigure, 'push');
            app.GetIInverseKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @GetIInverseKinematicsButtonPushed, true);
            app.GetIInverseKinematicsButton.Text = 'Get IInverse Kinematics';
            app.CoordinatesofEndEffectorLabel = uilabel(app.UIFigure);
            app.CoordinatesofEndEffectorLabel.Text = 'Coordinates of End Effector';
            app.CoordinateXEditFieldLabel = uilabel(app.UIFigure);
            app.CoordinateXEditFieldLabel.Text = 'Coordinate (X)';
            app.CoordinateXEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoordinateYEditFieldLabel = uilabel(app.UIFigure);
            app.CoordinateYEditFieldLabel.Text = 'Coordinate (Y)';
            app.CoordinateYEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoorindateZEditFieldLabel = uilabel(app.UIFigure);
            app.CoorindateZEditFieldLabel.Text = 'Coorindate (Z)';
            app.CoorindateZEditField = uieditfield(app.UIFigure, 'numeric');
            app.FwdHide = uiimage(app.UIFigure);
            app.FwdHide.Visible = 'off';
            app.FwdHide.ImageSource = 'Jean Gray block.jpeg';
            app.FollowPathButton = uibutton(app.UIFigure, 'push');
            app.FollowPathButton.ButtonPushedFcn = createCallbackFcn(app, @FollowPathButtonPushed, true);
            app.FollowPathButton.Text = 'Follow Path';
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.CenterXEditFieldLabel = uilabel(app.UIFigure);
            app.CenterXEditFieldLabel.Text = 'Center (X)';
            app.CenterXEditField = uieditfield(app.UIFigure, 'numeric');
            app.CenterYEditFieldLabel = uilabel(app.UIFigure);
            app.CenterYEditFieldLabel.Text = 'Center (Y)';
            app.CenterYEditField = uieditfield(app.UIFigure, 'numeric');
            app.CenterZEditFieldLabel = uilabel(app.UIFigure);
            app.CenterZEditFieldLabel.Text = 'Center (Z)';
            app.CenterZEditField = uieditfield(app.UIFigure, 'numeric');
            app.RadiusEditFieldLabel = uilabel(app.UIFigure);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.TherobotwilltracetheshapeoutlineLabel = uilabel(app.UIFigure);
            app.TherobotwilltracetheshapeoutlineLabel.Text = {'The robot will trace the '; 'shape outline'};
            app.InverseKinematicsLabel = uilabel(app.UIFigure);
            app.InverseKinematicsLabel.Text = 'Inverse Kinematics';
            app.PathFollowingLabel = uilabel(app.UIFigure);
            app.PathFollowingLabel.Text = 'Path Following';
            app.AngularVelocityoneachlinkRadsSecondLabel_3 = uilabel(app.UIFigure);
            app.AngularVelocityoneachlinkRadsSecondLabel_3.Text = {'This ''Get Inverse Kinematics'' button will '; 'calculate the inverse kinematics'};
            app.TheFollowPathButtonwillhavetheUR5tracetheshapeLabel = uilabel(app.UIFigure);
            app.TheFollowPathButtonwillhavetheUR5tracetheshapeLabel.Text = {'The ''Follow Path'' Button will '; 'have the UR5 trace the shape'};
            app.RadiusoftheshapeLabel = uilabel(app.UIFigure);
            app.RadiusoftheshapeLabel.Text = 'Radius of the shape';
            app.TheZcoordinateoftheshapecenterLabel = uilabel(app.UIFigure);
            app.TheZcoordinateoftheshapecenterLabel.Text = 'The Z coordinate of the shape center';
            app.ThetimetakentotracetheshapeLabel = uilabel(app.UIFigure);
            app.ThetimetakentotracetheshapeLabel.Text = 'The time taken to trace the shape';
            app.TheXcoordinateoftheshapecenterLabel = uilabel(app.UIFigure);
            app.TheXcoordinateoftheshapecenterLabel.Text = 'The X coordinate of the shape center';
            app.TheYcoordinateoftheshapecenterLabel = uilabel(app.UIFigure);
            app.TheYcoordinateoftheshapecenterLabel.Text = 'The Y coordinate of the shape center';
            app.IneachoftheseblocksenterthecoordinatesfortheendeffectorLabel = uilabel(app.UIFigure);
            app.IneachoftheseblocksenterthecoordinatesfortheendeffectorLabel.Text = {'In each of these blocks, enter the '; 'coordinates for the end effector'};
            app.InvHide = uiimage(app.UIFigure);
            app.InvHide.Visible = 'off';
            app.InvHide.ImageSource = 'Jean Gray block.jpeg';
            app.PathHide = uiimage(app.UIFigure);
            app.PathHide.ImageClickedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.PathHide.Visible = 'off';
            app.PathHide.ImageSource = 'Jean Gray block.jpeg';
            app.PathSwitchLabel = uilabel(app.UIFigure);
            app.PathSwitchLabel.Text = 'PathSwitch';
            app.PathSwitch = uiswitch(app.UIFigure, 'toggle');
            app.PathSwitch.ValueChangedFcn = createCallbackFcn(app, @PathSwitchValueChanged, true);
            app.InverseSwitchLabel = uilabel(app.UIFigure);
            app.InverseSwitchLabel.Text = 'InverseSwitch';
            app.InverseSwitch = uiswitch(app.UIFigure, 'toggle');
            app.InverseSwitch.ValueChangedFcn = createCallbackFcn(app, @InverseSwitchValueChanged, true);
            app.ForwardSwitchLabel = uilabel(app.UIFigure);
            app.ForwardSwitchLabel.Text = {'Forward '; 'Switch'};
            app.ForwardSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ForwardSwitch.ValueChangedFcn = createCallbackFcn(app, @ForwardSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MasterGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end